classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultWaitingforInputLabel  matlab.ui.control.Label
        CalculatePredictionButton   matlab.ui.control.Button
        Label5                      matlab.ui.control.Label
        Label4                      matlab.ui.control.Label
        Label3                      matlab.ui.control.Label
        Label2                      matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        OldpeakEditField            matlab.ui.control.NumericEditField
        OldpeakEditFieldLabel       matlab.ui.control.Label
        ThalachEditField            matlab.ui.control.NumericEditField
        ThalachEditFieldLabel       matlab.ui.control.Label
        CholEditField               matlab.ui.control.NumericEditField
        CholEditFieldLabel          matlab.ui.control.Label
        TrestbpsEditField           matlab.ui.control.NumericEditField
        TrestbpsEditFieldLabel      matlab.ui.control.Label
        AgeEditField                matlab.ui.control.NumericEditField
        Label_2                     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    
    methods (Access = private)
        function startupFcn(app)
            % Model ve Normalizasyon değişkenlerini dinamik özellik olarak ekle
            % (Sadece özellik henüz yoksa ekler)
            if ~isprop(app, 'mdl_final')
                addprop(app, 'mdl_final');
            end
            if ~isprop(app, 'M_train')
                addprop(app, 'M_train');
            end
            if ~isprop(app, 'S_train')
                addprop(app, 'S_train');
            end
            
            % Değerleri Ata
            app.M_train = [55.3, 131.7, 246.0, 149.6, 1.05]; 
            app.S_train = [9.05, 17.6, 49.3, 23.3, 1.15];
            
            % Kaydedilen modeli yükle
            try
                loadedData = load('kalp_tahmin_modeli_final.mat', 'mdl_final');
                app.mdl_final = loadedData.mdl_final;
            catch
                uialert(app.UIFigure, 'Model dosyası bulunamadı!', 'Hata');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculatePredictionButton
        function CalculatePredictionButtonPushed(app, event)
  
            % 1. Kullanıcıdan Girdileri Toplama
            age_val = app.AgeEditField.Value;
            trestbps_val = app.TrestbpsEditField.Value;
            chol_val = app.CholEditField.Value;
            thalach_val = app.ThalachEditField.Value;
            oldpeak_val = app.OldpeakEditField.Value;

            raw_input = [age_val, trestbps_val, chol_val, thalach_val, oldpeak_val];

            % 2. Normalizasyon Parametreleri (Modelin eğitimiyle aynı olmalı)
            M_train_val = [55.3, 131.7, 246.0, 149.6, 1.05]; 
            S_train_val = [9.05, 17.6, 49.3, 23.3, 1.15];   
            
            % Girdiyi Normalize Et
            normalized_input = (raw_input - M_train_val) ./ S_train_val;

            % 3. Modeli Yükle ve Tahmin Yap
            try
                loadedData = load('kalp_tahmin_modeli_final.mat');
                myModel = loadedData.mdl_final; 
                
                % Tahmin
                [YPred, ~] = predict(myModel, normalized_input); 
                
                % 4. Sonucu ve Olasılığı Ekrana Yazdır
                if strcmp(string(YPred), 'Yes')
                    app.ResultWaitingforInputLabel.Text = 'SONUÇ: YÜKSEK RİSK! (Kalp Hastalığı)';
                    app.ResultWaitingforInputLabel.FontColor = [0.8 0 0]; % Kırmızı
                    status_color = 'r';
                else
                    app.ResultWaitingforInputLabel.Text = 'SONUÇ: DÜŞÜK RİSK (Sağlıklı)';
                    app.ResultWaitingforInputLabel.FontColor = [0 0.5 0]; % Yeşil
                    status_color = 'g';
                end
                
                % --- GÖRSELLEŞTİRME BÖLÜMÜ (HİSTOGRAM) ---
                % Kullanıcının yaşını, genel yaş dağılımı üzerinde gösterelim.
                
                % Örnek veri dağılımı (Ortalama 55, Std 10 olan rastgele 1000 kişi)
                % Gerçek veri setinizi buraya yükleyebilirseniz daha iyi olur, ancak örnek veri de iş görür.
                ornek_veri = 55 + 10 * randn(1000, 1); 
                
                % Histogramı UIAxes üzerinde çiz
                % ÖNEMLİ: Tasarım görünümünde bir "UIAxes" eklemiş olmanız gerekir.
                % Eğer yoksa hata verir. Adı app.UIAxes olmalı.
                
                if isprop(app, 'UIAxes') % UIAxes var mı kontrol et
                    histogram(app.UIAxes, ornek_veri, 'FaceColor', [0.7 0.7 0.7], 'EdgeColor', 'none');
                    hold(app.UIAxes, 'on');
                    
                    % Kullanıcının yaşını çizgiyle işaretle
                    xline(app.UIAxes, age_val, status_color, 'LineWidth', 3, 'Label', 'Siz');
                    
                    % Grafiği Düzenle
                    title(app.UIAxes, 'Yaş Dağılımı ve Konumunuz');
                    xlabel(app.UIAxes, 'Yaş');
                    ylabel(app.UIAxes, 'Kişi Sayısı');
                    legend(app.UIAxes, {'Genel Popülasyon', 'Sizin Değeriniz'});
                    hold(app.UIAxes, 'off');
                else
                    % Eğer UIAxes yoksa uyarı ver (veya figure ile yeni pencere aç)
                    figure('Name', 'Analiz Sonucu');
                    histogram(ornek_veri, 'FaceColor', [0.7 0.7 0.7], 'EdgeColor', 'none');
                    hold on;
                    xline(age_val, status_color, 'LineWidth', 3, 'Label', 'Siz');
                    title('Yaş Dağılımı ve Konumunuz');
                    xlabel('Yaş');
                    ylabel('Kişi Sayısı');
                    legend({'Genel Popülasyon', 'Sizin Değeriniz'});
                    hold off;
                end
                
            catch ME
                app.ResultWaitingforInputLabel.Text = 'Hata: Tahmin veya Görselleştirme Yapılamadı.';
                disp(ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Analyse Graph')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrestbpsEditFieldLabel = uilabel(app.UIFigure);
            app.TrestbpsEditFieldLabel.Text = 'Trestbps';
            app.TrestbpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CholEditFieldLabel = uilabel(app.UIFigure);
            app.CholEditFieldLabel.Text = 'Chol';
            app.CholEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThalachEditFieldLabel = uilabel(app.UIFigure);
            app.ThalachEditFieldLabel.Text = 'Thalach';
            app.ThalachEditField = uieditfield(app.UIFigure, 'numeric');
            app.OldpeakEditFieldLabel = uilabel(app.UIFigure);
            app.OldpeakEditFieldLabel.Text = 'Oldpeak';
            app.OldpeakEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Label5';
            app.CalculatePredictionButton = uibutton(app.UIFigure, 'push');
            app.CalculatePredictionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePredictionButtonPushed, true);
            app.CalculatePredictionButton.Text = 'Calculate Prediction';
            app.ResultWaitingforInputLabel = uilabel(app.UIFigure);
            app.ResultWaitingforInputLabel.Text = 'Result: Waiting for Input..';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.ResultWaitingforInputLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end